classdef GUIpinholeCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusbarProcessingprogressTextArea  matlab.ui.control.TextArea
        StatusbarProcessingprogressTextAreaLabel  matlab.ui.control.Label
        ParameterCalculatepinholePanel  matlab.ui.container.Panel
        SaveParamButton                 matlab.ui.control.Button
        GeneralPinholeButton            matlab.ui.control.Button
        SubpixelCheckBox                matlab.ui.control.CheckBox
        DisplayCheckBox                 matlab.ui.control.CheckBox
        v_f2xv_f2yEditField             matlab.ui.control.EditField
        v_f2xv_f2yEditFieldLabel        matlab.ui.control.Label
        v_f1xv_f1yEditField             matlab.ui.control.EditField
        v_f1xv_f1yLabel                 matlab.ui.control.Label
        AlreadyknowthebasicvectorsCheckBox  matlab.ui.control.CheckBox
        CalBasicVecButton               matlab.ui.control.Button
        ReferenceDropDown               matlab.ui.control.DropDown
        ReferenceDropDownLabel          matlab.ui.control.Label
        ExtentEditField                 matlab.ui.control.NumericEditField
        ExtentEditFieldLabel            matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        UpfactorEditField               matlab.ui.control.NumericEditField
        UpfactorEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileNum % the num of raw image
    end
    
    properties (Access = public)
        app_parent % pointer to store the mian figure
        SETUP % parameters related to calculate pinhole
        file_path % file path of raw image
        file_num % the num of raw image
        CellArrayText % output
    end
    
    methods (Access = private)
        
        function output(app, str)
            %% output function in status bar
            string = sprintf('%s\n', str);
            app.CellArrayText = horzcat(app.CellArrayText,string);
            app.StatusbarProcessingprogressTextArea.Value = app.CellArrayText;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent)
            app.app_parent = app_parent;
            app.UpfactorEditField.Value = app_parent.Upfactor.Value;
            app.file_path = app_parent.Filepath.Value;
            app.file_num = length(dir([app.file_path, '\pinhole_ALN_raw'])) - 2;
            for imgIdx = 1 : app.file_num
                app.ReferenceDropDown.Items{end+1} =num2str(imgIdx);
            end
            app.CellArrayText{1} = sprintf('%s\n', '***This module is used to calculation pinhole parameters***');
            app.StatusbarProcessingprogressTextArea.Value = app.CellArrayText{1};
            app.SETUP.upfactor = app_parent.Upfactor.Value;
            app.SETUP.range = app.RangeEditField.Value;
            app.SETUP.extent = app.ExtentEditField.Value;
            app.SETUP.reference = app.ReferenceDropDown.Value;
            app.SETUP.imgStack = zeros(2048, 2048, app.file_num);
            app.SETUP.vec_estimate_manual = zeros(2);
            app.SETUP.basicVec_fourier = zeros(2);
            app.SETUP.basicVec_spatial = zeros(2);
            app.SETUP.offsetVec = zeros(app.file_num, 2);
            app.SETUP.illumination = zeros(2048, 2048, app.file_num);
            app.SETUP.para_save_path = '';
        end

        % Callback function
        function ApplyParamButtonPushed(app, event)
            app.app_parent.RunPinholeButton.Enable = 'on';
% %             delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.app_parent.RunPinholeButton.Enable = 'on';
            app.app_parent.SETUP = app.SETUP;
            delete(app)
        end

        % Button pushed function: CalBasicVecButton
        function CalBasicVecButtonPushed(app, event)
             %% Calculate Pinhole's basic vectors
             app.CalBasicVecButton.Enable = "off";
             Level1_pinholeCal;
             output(app, ['Basic vectors in Fourier domain:(',num2str(app.SETUP.basicVec_fourier(1,:)),...
                 '),(',num2str(app.SETUP.basicVec_fourier(2,:)),');']);
             output(app, ['Basic vectors in Spatial domain:(',num2str(app.SETUP.basicVec_spatial(1,:)),...
                 '),(',num2str(app.SETUP.basicVec_spatial(2,:)),');']);
             app.CalBasicVecButton.Enable = "on";
        end

        % Value changed function: AlreadyknowthebasicvectorsCheckBox
        function AlreadyknowthebasicvectorsCheckBoxValueChanged(app, event)
            value = app.AlreadyknowthebasicvectorsCheckBox.Value;
            if value == true
                app.v_f1xv_f1yEditField.Editable = true;
                app.v_f1xv_f1yEditField.Enable = true;
                app.v_f2xv_f2yEditField.Editable = true;
                app.v_f2xv_f2yEditField.Enable = true;
            else
                app.v_f1xv_f1yEditField.Editable = false;
                app.v_f1xv_f1yEditField.Enable = false;
                app.v_f2xv_f2yEditField.Editable = false;
                app.v_f2xv_f2yEditField.Enable = false;
            end
        end

        % Button pushed function: SaveParamButton
        function SaveParamButtonPushed(app, event)
            app.SaveParamButton.Enable = "off";
            Para_Save;
            app.SaveParamButton.Enable = "on";
            output(app, ['Parameter is saved at: ', app.SETUP.para_save_path])
        end

        % Button pushed function: GeneralPinholeButton
        function GeneralPinholeButtonPushed(app, event)
            app.GeneralPinholeButton.Enable = "off";
            Level1_illuminationGen;
            output(app, 'Illumination patterns have been generated!');
            app.GeneralPinholeButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParameterCalculatepinholePanel = uipanel(app.UIFigure);
            app.ParameterCalculatepinholePanel.Title = 'Parameter (Calculate pinhole)';
            app.UpfactorEditFieldLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.UpfactorEditFieldLabel.Text = 'Upfactor';
            app.UpfactorEditField = uieditfield(app.ParameterCalculatepinholePanel, 'numeric');
            app.UpfactorEditField.Value = 1;
            app.RangeEditFieldLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.ParameterCalculatepinholePanel, 'numeric');
            app.RangeEditField.Value = 2;
            app.ExtentEditFieldLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.ExtentEditFieldLabel.Text = 'Extent';
            app.ExtentEditField = uieditfield(app.ParameterCalculatepinholePanel, 'numeric');
            app.ExtentEditField.Value = 8;
            app.ReferenceDropDownLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.ParameterCalculatepinholePanel);
            app.ReferenceDropDown.Items = {'Sum'};
            app.ReferenceDropDown.Value = 'Sum';
            app.CalBasicVecButton = uibutton(app.ParameterCalculatepinholePanel, 'push');
            app.CalBasicVecButton.ButtonPushedFcn = createCallbackFcn(app, @CalBasicVecButtonPushed, true);
            app.CalBasicVecButton.Text = 'CalBasicVec';
            app.AlreadyknowthebasicvectorsCheckBox = uicheckbox(app.ParameterCalculatepinholePanel);
            app.AlreadyknowthebasicvectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlreadyknowthebasicvectorsCheckBoxValueChanged, true);
            app.AlreadyknowthebasicvectorsCheckBox.Text = 'Already know the basic vectors?';
            app.v_f1xv_f1yLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.v_f1xv_f1yLabel.Interpreter = 'latex';
            app.v_f1xv_f1yLabel.Text = '$v_{f1x}$  $v_{f1y}$';
            app.v_f1xv_f1yEditField = uieditfield(app.ParameterCalculatepinholePanel, 'text');
            app.v_f1xv_f1yEditField.Editable = 'off';
            app.v_f1xv_f1yEditField.Enable = 'off';
            app.v_f1xv_f1yEditField.Value = '0 0';
            app.v_f2xv_f2yEditFieldLabel = uilabel(app.ParameterCalculatepinholePanel);
            app.v_f2xv_f2yEditFieldLabel.Interpreter = 'latex';
            app.v_f2xv_f2yEditFieldLabel.Text = '$v_{f2x}$  $v_{f2y}$';
            app.v_f2xv_f2yEditField = uieditfield(app.ParameterCalculatepinholePanel, 'text');
            app.v_f2xv_f2yEditField.Editable = 'off';
            app.v_f2xv_f2yEditField.Enable = 'off';
            app.v_f2xv_f2yEditField.Value = '0 0';
            app.DisplayCheckBox = uicheckbox(app.ParameterCalculatepinholePanel);
            app.DisplayCheckBox.Text = 'Display';
            app.SubpixelCheckBox = uicheckbox(app.ParameterCalculatepinholePanel);
            app.SubpixelCheckBox.Text = 'Subpixel';
            app.GeneralPinholeButton = uibutton(app.ParameterCalculatepinholePanel, 'push');
            app.GeneralPinholeButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralPinholeButtonPushed, true);
            app.GeneralPinholeButton.Text = 'GeneralPinhole';
            app.SaveParamButton = uibutton(app.ParameterCalculatepinholePanel, 'push');
            app.SaveParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamButtonPushed, true);
            app.SaveParamButton.Text = 'SaveParam';
            app.StatusbarProcessingprogressTextAreaLabel = uilabel(app.UIFigure);
            app.StatusbarProcessingprogressTextAreaLabel.Text = 'Status bar: Processing progress';
            app.StatusbarProcessingprogressTextArea = uitextarea(app.UIFigure);
            app.StatusbarProcessingprogressTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIpinholeCal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end