classdef MCISMXi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Filepath                     matlab.ui.control.EditField
        ViewFile                     matlab.ui.control.Button
        ReadFile                     matlab.ui.control.Button
        PreprocessLabel              matlab.ui.control.Label
        LockinDeDc                   matlab.ui.control.Button
        HardwareParametersPanel      matlab.ui.container.Panel
        ExW                          matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        Upfactor                     matlab.ui.control.NumericEditField
        Label_12                     matlab.ui.control.Label
        step                         matlab.ui.control.NumericEditField
        Label_10                     matlab.ui.control.Label
        nmLabel_3                    matlab.ui.control.Label
        PixelSize                    matlab.ui.control.NumericEditField
        pixelsizeLabel               matlab.ui.control.Label
        NumericalAperture            matlab.ui.control.NumericEditField
        NALabel                      matlab.ui.control.Label
        EmW                          matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        nmLabel_2                    matlab.ui.control.Label
        nmLabel                      matlab.ui.control.Label
        MCISMPixelReassignmentPanel  matlab.ui.container.Panel
        Bg                           matlab.ui.control.NumericEditField
        Label_13                     matlab.ui.control.Label
        RunMSIMButton                matlab.ui.control.Button
        Edge                         matlab.ui.control.NumericEditField
        EdgetaperCheckBox            matlab.ui.control.CheckBox
        WS                           matlab.ui.control.NumericEditField
        WindowSizeLabel              matlab.ui.control.Label
        pinhole                      matlab.ui.control.NumericEditField
        DigitalpinholeCheckBox       matlab.ui.control.CheckBox
        DeconvCheckBox               matlab.ui.control.CheckBox
        PRCheckBox                   matlab.ui.control.CheckBox
        ConfocalCheckBox             matlab.ui.control.CheckBox
        LiWFCheckBox                 matlab.ui.control.CheckBox
        IlluminationPatternGenerationPanel  matlab.ui.container.Panel
        UpdateButton                 matlab.ui.control.Button
        PinholeFilepath              matlab.ui.control.EditField
        RunPinholeButton             matlab.ui.control.Button
        DisplayFile                  matlab.ui.control.Button
        RefCheckBox                  matlab.ui.control.CheckBox
        jRLPanel                     matlab.ui.container.Panel
        RunjRLButton                 matlab.ui.control.Button
        FreqjRL                      matlab.ui.control.NumericEditField
        Label_7                      matlab.ui.control.Label
        IterjRL                      matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        FISTAPanel                   matlab.ui.container.Panel
        ReconstructionProcessButtonGroup  matlab.ui.container.ButtonGroup
        ACBGButton                   matlab.ui.control.RadioButton
        OnlyGButton                  matlab.ui.control.RadioButton
        RegularItemButtonGroup       matlab.ui.container.ButtonGroup
        GSButton                     matlab.ui.control.RadioButton
        NonButton                    matlab.ui.control.RadioButton
        lambda_2                     matlab.ui.control.NumericEditField
        l                            matlab.ui.control.NumericEditField
        LLabel                       matlab.ui.control.Label
        lambda                       matlab.ui.control.NumericEditField
        LamdaLabel                   matlab.ui.control.Label
        FreqFISTA                    matlab.ui.control.NumericEditField
        fre_saveLabel                matlab.ui.control.Label
        IterFISTA                    matlab.ui.control.NumericEditField
        N_iterLabel                  matlab.ui.control.Label
        RunFISTAButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        Raw                          matlab.ui.container.Tab
        RawImage                     matlab.ui.control.UIAxes
        BatchProcessingTab           matlab.ui.container.Tab
        FileSetupPanel               matlab.ui.container.Panel
        Zstart                       matlab.ui.control.NumericEditField
        Zend                         matlab.ui.control.NumericEditField
        ViewFileBatch                matlab.ui.control.Button
        FilepathBatch                matlab.ui.control.EditField
        Label_15                     matlab.ui.control.Label
        FilepathSaveBatch            matlab.ui.control.EditField
        ViewFileSaveBatch            matlab.ui.control.Button
        FastAixsDummy                matlab.ui.control.CheckBox
        SlowAxisDummy                matlab.ui.control.CheckBox
        isWF                         matlab.ui.control.CheckBox
        isrename                     matlab.ui.control.CheckBox
        Label_24                     matlab.ui.control.Label
        Label_25                     matlab.ui.control.Label
        TStart                       matlab.ui.control.EditField
        TEnd                         matlab.ui.control.EditField
        Label_23                     matlab.ui.control.Label
        ImageSequenceLabel           matlab.ui.control.Label
        CCheckBox                    matlab.ui.control.CheckBox
        Background                   matlab.ui.control.EditField
        Wavelength                   matlab.ui.control.EditField
        ZCheckBox                    matlab.ui.control.CheckBox
        TCheckBox                    matlab.ui.control.CheckBox
        PipelineRefertotheparametersontheleftPanel  matlab.ui.container.Panel
        RawdataBCheckBox             matlab.ui.control.CheckBox
        LockinDeDCBCheckBox          matlab.ui.control.CheckBox
        PinholeBCheckBox             matlab.ui.control.CheckBox
        PRBCheckBox                  matlab.ui.control.CheckBox
        jRLBCheckBox                 matlab.ui.control.CheckBox
        FISTABCheckBox               matlab.ui.control.CheckBox
        RunBatchButton               matlab.ui.control.Button
        StatusBarProcessingProgressTextArea  matlab.ui.control.TextArea
        Label_11                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        CellArrayText 
        SETUP
    end
    
    properties (Access = private)
        pinholeCalApp % pinholeCal App
    end
    
    methods (Access = private)
        
        function outPut(app,str)
            %% output function in status bar
            string = sprintf('%s\n', str);
            app.CellArrayText = horzcat(app.CellArrayText,string);
            app.StatusBarProcessingProgressTextArea.Value = app.CellArrayText;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CellArrayText{1} = sprintf('%s\n', '***This module is used to output processing step information***');
            app.StatusBarProcessingProgressTextArea.Value = app.CellArrayText{1};
            app.UIFigure.Name = 'MC-ISM';
        end

        % Button pushed function: ViewFile
        function ViewFileButtonPushed(app, event)
            %% 0.1 Browse the original file path and display it in the text box
            filepath = uigetdir;
            app.Filepath.Value = filepath;
        end

        % Button pushed function: ReadFile
        function ReadFileButtonPushed(app, event)
            %% 0.2 Display the first frame 
            % Read filepath
            addpath('./func/')
            filepath = app.Filepath.Value;
            filepath_raw = [filepath, '\pinhole_ALN_raw'];
            fileinfo_raw = dir(filepath_raw);
            filename = strcat(filepath_raw,'\',fileinfo_raw(3).name);
            A = double(imread(filename));
            A = A/max(A(:));
            % display the first frame
            imshow(A,'Parent',app.RawImage)
            outPut(app, '---1. Files have been read in')
        end

        % Button pushed function: LockinDeDc
        function LockinDeDcButtonPushed(app, event)
            %% 1. Lockin DeDC is performed before image recontruction
            outPut(app, '---2. [OLID].............')
            filepath = app.Filepath.Value;
            Level1_LockinDeDC;
            outPut(app, '---2. [Lockin DeDC] has been Completed!')
        end

        % Button pushed function: RunMSIMButton
        function RunMSIMButtonPushed(app, event)
            %% 2. Pixel reassignment reconstruction
            outPut(app, '---3. [MC-ISM pixel reassignment reconstruction].............')
            % 2.1. Read hardware parameters
            lambda_ex = app.ExW.Value;
            lambda_em = app.EmW.Value;
            bg = app.Bg.Value;   
            Para_basic;
            % 2.2. Read filepath
            filepath = app.Filepath.Value;
            % 2.3. PR reconstruction
            Level1_spatial_PR; 
            outPut(app, '---3. [MC-ISM pixel reassignment reconstruction] has been Completed!')
        end

        % Button pushed function: DisplayFile
        function DisplayFileButtonPushed(app, event)
            %% 3. Read reference parameters for illumination patterns
            filename = uigetfile;
            app.PinholeFilepath.Value = filename;
        end

        % Button pushed function: RunPinholeButton
        function RunPinholeButtonPushed(app, event)
            %% 4. Calculated illumination pattern
            outPut(app, '---4. [Illumination pattern calculation].............')
            app.RunPinholeButton.Enable = 'off';
            app.pinholeCalApp = GUIpinholeCal(app);
        end

        % Button pushed function: RunjRLButton
        function RunjRLButtonPushed(app, event)
            %% 5. jRL deconvolution reconstruction
            outPut(app, '---5. [jRL].............')
            addpath('.\func\')
            % 5.1 read and set parameters
            Iteration = app.IterjRL.Value;
            SaveFreq = app.FreqjRL.Value;
            lambda_ex = app.ExW.Value;
            lambda_em = app.EmW.Value;
            % 5.2 read filepath
            filepath = app.Filepath.Value;
            filesave = strcat(filepath, '\deconv_result\jRL_Ex',num2str(app.ExW.Value));
            mkdir(filesave)
            % 5.3 Determine whether to calculate the illumination pattern directly
            if app.RefCheckBox.Value == 1
                load(app.PinholeFilepath.Value);
            else
                load([filepath, '\SETUP.mat']);
            end
            % 5.4 jRL deconvolution reconstruction
            Level1_jRL;
            outPut(app, '---5. [jRL] has been completer!')
        end

        % Button pushed function: RunFISTAButton
        function RunFISTAButtonPushed(app, event)
            %% 6. FISTA deconvolution reconstruction
            outPut(app, '---6. [FISTA].............')
            addpath('.\func\')

            % 6.1 read and set parameters
            lambda1 = app.lambda.Value;
            lambda2 = app.lambda_2.Value;
            L = app.l.Value;
            Iteration = app.IterFISTA.Value;
            SaveFreq = app.FreqFISTA.Value;
            bg = app.Bg.Value;
            RegItem = [app.NonButton.Value, app.GSButton.Value];
            exeItem = [app.OnlyGButton.Value, app.ACBGButton.Value];
            
            lambda_ex = app.ExW.Value;
            lambda_em = app.EmW.Value;
            
            % 6.2 read filepath and set savepath
            filepath = app.Filepath.Value;
            if exeItem(1) == 1
                filesave = strcat(filepath, '\deconv_result\FISTA_Ex',...
                            num2str(lambda_ex),'_lam1_',num2str(lambda1),'_lam2_',...
                            num2str(lambda2),'_L_',num2str(L));
            elseif exeItem(2) == 1
                filesave = strcat(filepath, '\deconv_result\FISTA_Ex',...
                            num2str(lambda_ex),'_lam1_',num2str(lambda1),'_lam2_',...
                            num2str(lambda2),'_L_',num2str(L),'_ACbg');
            end

            if RegItem(2) == 1
                filesave = [filesave, '_GroupSparsity'];
            end
            mkdir(filesave)
            
            if app.RefCheckBox.Value == 1
                load(app.PinholeFilepath.Value);
            else
                load([filepath, '\SETUP.mat']);
            end

            % 6.3 FISTA deconvolution reconstruction
            Level1_FISTA;
            outPut(app, '---6. [FISTA] has been completed!')
        end

        % Button pushed function: ViewFileBatch
        function ViewFileBatchButtonPushed(app, event)
            filepath = uigetdir;
            app.FilepathBatch.Value = filepath;
            app.FilepathSaveBatch.Value = [filepath,'\data_grouped'];
        end

        % Button pushed function: ViewFileSaveBatch
        function ViewFileSaveBatchButtonPushed(app, event)
            filepath = uigetdir;
            app.FilepathSaveBatch.Value = filepath;
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            %% bulk mode
            outPut(app, '---------Batch Process---------')
            addpath('.\func\')
            % 1. Read the folder where the path is saved
            dirpath = app.FilepathSaveBatch.Value;
            % 2. Determine the current folder structure
            %    2.1 isC: multi-channel; isZ: Z-stack; isT: long-term. 
            %        To choose neither is to process a single image

            isC = app.CCheckBox.Value;
            isZ = app.ZCheckBox.Value;
            isT = app.TCheckBox.Value;
            if isC
                Lambda = app.Wavelength.Value;
                Lam = strsplit(Lambda, ';');
                BG = strsplit(app.Background.Value, ';');
                CNum = length(Lam);
                for CIdx = 1:CNum
                    temp = strsplit(Lam{CIdx}, ',');
                    Lambda_ex(CIdx) = str2double(temp{1});
                    Lambda_em(CIdx) = str2double(temp{2});
                    bgC(CIdx) = str2double(BG{CIdx});
                end
            else
                CNum = 1;
                lambda_ex = app.ExW.Value;
                lambda_em = app.EmW.Value;
            end
            
            % 3. Determine the process flow
            isRawDataB = app.RawdataBCheckBox.Value;
            isPinholeB = app.PinholeBCheckBox.Value;
            isLockinB = app.LockinDeDCBCheckBox.Value;
            isPRB = app.PRBCheckBox.Value;
            isjRLB = app.jRLBCheckBox.Value;
            isFISTAB = app.FISTABCheckBox.Value;
            isRename = app.isrename.Value;

            %    3.1 isRawData: Organize folders according to the above structure
            %                   The lowest layer is the color channel
            if isRawDataB
                outPut(app, '---【Batch】[Data Arrangement].............')

                % Get the number of T and the number of z, generate the file path "filepathAll"
                Batch1_FileArra; 
                outPut(app, '---【Batch】[Data Arrangement] has been completed!')
            end
            
            
            %    3.2 Lockin DeDC
            if isLockinB
                outPut(app, '---【Batch】[Lockin DeDC].............')
                dirinfo1 = dir(dirpath);
                dirinfo1_name = struct2cell(dirinfo1);
                dirinfo1_name = dirinfo1_name(1,:);

                Batch1_LockinDeDC;
                outPut(app, '---【Batch】[Lockin DeDC] has been completed!')
            end


            %    3.3 ispinhole：Batch processing; If batch processing is 
            %                   not selected, use the reference frame on the left
            if isPinholeB
                outPut(app, '---【Batch】[Illumination pattern calculation].............')
                dirinfo1 = dir(dirpath);
                dirinfo1_name = struct2cell(dirinfo1);
                dirinfo1_name = dirinfo1_name(1,:);

                Batch1_pinholeCal;

                outPut(app, '---【Batch】[Illumination pattern calculation] has been comleted!')
            end

            %    3.4 traditional PR，jRL，FISTA are calculated 
            %        according to the parameters on the left 
            %        except for lambda_ex, lambda_em, and bg
            if isPRB
                outPut(app, '---【Batch】[Traditional MSIM reconstruction].............')
                dirinfo1 = dir(dirpath);
                dirinfo1_name = struct2cell(dirinfo1);
                dirinfo1_name = dirinfo1_name(1,:);

                Batch1_spatial_PR;

                outPut(app, '---【Batch】[Traditional MSIM reconstruction] has been completed!')
            end

            if isjRLB % Determine wavelength and background in deconvolution
                outPut(app, '---【Batch】[jRL].............')
                Iteration = app.IterjRL.Value;
                SaveFreq = app.FreqjRL.Value;
                lambda_ex = app.ExW.Value;
                lambda_em = app.EmW.Value;

                dirinfo1 = dir(dirpath);
                dirinfo1_name = struct2cell(dirinfo1);
                dirinfo1_name = dirinfo1_name(1,:);
                
                Batch1_jRL;

                outPut(app, '---【Batch】[jRL] has been completed!')
            end

            if isFISTAB % Determine wavelength and background in deconvolution
                outPut(app, '---【Batch】[FISTA].............')
                lambda1 = app.lambda.Value;
                lambda2 = app.lambda_2.Value;
                L = app.l.Value;
                Iteration = app.IterFISTA.Value;
                SaveFreq = app.FreqFISTA.Value;
%                 bg = app.Bg.Value;
                RegItem = [app.NonButton.Value, app.SButton.Value, app.GSButton.Value];
                exeItem = [app.OnlyGButton.Value, app.ACBGButton.Value, app.ACDCButton.Value];
                
%                 lambda_ex = app.ExW.Value;
%                 lambda_em = app.EmW.Value;

                dirinfo1 = dir(dirpath);
                dirinfo1_name = struct2cell(dirinfo1);
                dirinfo1_name = dirinfo1_name(1,:);
                
                Batch1_FISTA;
                
                outPut(app, '---【Batch】[FISTA] has been completed!')
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.RunPinholeButton.Enable = 'on';
            app.SETUP = app.pinholeCalApp.SETUP;
            delete(app.pinholeCalApp);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {610, 610};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {288, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Filepath:';
            app.FISTAPanel = uipanel(app.LeftPanel);
            app.FISTAPanel.Title = 'FISTA';
            app.RunFISTAButton = uibutton(app.FISTAPanel, 'push');
            app.RunFISTAButton.ButtonPushedFcn = createCallbackFcn(app, @RunFISTAButtonPushed, true);
            app.RunFISTAButton.Text = 'Run';
            app.N_iterLabel = uilabel(app.FISTAPanel);
            app.N_iterLabel.Interpreter = 'latex';
            app.N_iterLabel.Text = '$N_{iter}$:';
            app.IterFISTA = uieditfield(app.FISTAPanel, 'numeric');
            app.IterFISTA.Value = 30;
            app.fre_saveLabel = uilabel(app.FISTAPanel);
            app.fre_saveLabel.Interpreter = 'latex';
            app.fre_saveLabel.Text = '$fre_{save}$:';
            app.FreqFISTA = uieditfield(app.FISTAPanel, 'numeric');
            app.FreqFISTA.Value = 20;
            app.LamdaLabel = uilabel(app.FISTAPanel);
            app.LamdaLabel.Interpreter = 'latex';
            app.LamdaLabel.Text = '$\lambda$:';
            app.lambda = uieditfield(app.FISTAPanel, 'numeric');
            app.lambda.Value = 0.005;
            app.LLabel = uilabel(app.FISTAPanel);
            app.LLabel.Text = 'L:';
            app.l = uieditfield(app.FISTAPanel, 'numeric');
            app.l.Value = 10;
            app.lambda_2 = uieditfield(app.FISTAPanel, 'numeric');
            app.lambda_2.Value = 10;
            app.RegularItemButtonGroup = uibuttongroup(app.FISTAPanel);
            app.RegularItemButtonGroup.Title = 'Regular Item';
            app.NonButton = uiradiobutton(app.RegularItemButtonGroup);
            app.NonButton.Text = 'None';
            app.NonButton.Value = true;
            app.GSButton = uiradiobutton(app.RegularItemButtonGroup);
            app.GSButton.Text = 'GS';
            app.ReconstructionProcessButtonGroup = uibuttongroup(app.FISTAPanel);
            app.ReconstructionProcessButtonGroup.Title = 'Reconstruction Process';
            app.OnlyGButton = uiradiobutton(app.ReconstructionProcessButtonGroup);
            app.OnlyGButton.Text = 'single';
            app.OnlyGButton.Value = true;
            app.ACBGButton = uiradiobutton(app.ReconstructionProcessButtonGroup);
            app.ACBGButton.Text = 'single+bg';
            app.jRLPanel = uipanel(app.LeftPanel);
            app.jRLPanel.Title = 'jRL';
            app.Label_6 = uilabel(app.jRLPanel);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '$N_{iter}$: ';
            app.IterjRL = uieditfield(app.jRLPanel, 'numeric');
            app.IterjRL.Value = 30;
            app.Label_7 = uilabel(app.jRLPanel);
            app.Label_7.Interpreter = 'latex';
            app.Label_7.Text = '$fre_{save}$: ';
            app.FreqjRL = uieditfield(app.jRLPanel, 'numeric');
            app.FreqjRL.Value = 10;
            app.RunjRLButton = uibutton(app.jRLPanel, 'push');
            app.RunjRLButton.ButtonPushedFcn = createCallbackFcn(app, @RunjRLButtonPushed, true);
            app.RunjRLButton.Text = 'Run';
            app.IlluminationPatternGenerationPanel = uipanel(app.LeftPanel);
            app.IlluminationPatternGenerationPanel.Title = 'Illumination Pattern Generation';
            app.RefCheckBox = uicheckbox(app.IlluminationPatternGenerationPanel);
            app.RefCheckBox.Text = 'Ref #';
            app.DisplayFile = uibutton(app.IlluminationPatternGenerationPanel, 'push');
            app.DisplayFile.ButtonPushedFcn = createCallbackFcn(app, @DisplayFileButtonPushed, true);
            app.DisplayFile.Text = 'view';
            app.RunPinholeButton = uibutton(app.IlluminationPatternGenerationPanel, 'push');
            app.RunPinholeButton.ButtonPushedFcn = createCallbackFcn(app, @RunPinholeButtonPushed, true);
            app.RunPinholeButton.Text = 'Run';
            app.PinholeFilepath = uieditfield(app.IlluminationPatternGenerationPanel, 'text');
            app.UpdateButton = uibutton(app.IlluminationPatternGenerationPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.MCISMPixelReassignmentPanel = uipanel(app.LeftPanel);
            app.MCISMPixelReassignmentPanel.Title = 'MC-ISM Pixel Reassignment';
            app.LiWFCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.LiWFCheckBox.Text = 'LiWF';
            app.LiWFCheckBox.Value = true;
            app.ConfocalCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.ConfocalCheckBox.Text = 'Confocal';
            app.ConfocalCheckBox.Value = true;
            app.PRCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.PRCheckBox.Text = 'PR';
            app.PRCheckBox.Value = true;
            app.DeconvCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.DeconvCheckBox.Text = 'Deconv';
            app.DeconvCheckBox.Value = true;
            app.DigitalpinholeCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.DigitalpinholeCheckBox.Text = 'Pinholing';
            app.pinhole = uieditfield(app.MCISMPixelReassignmentPanel, 'numeric');
            app.WindowSizeLabel = uilabel(app.MCISMPixelReassignmentPanel);
            app.WindowSizeLabel.Text = 'WindowSize: ';
            app.WS = uieditfield(app.MCISMPixelReassignmentPanel, 'numeric');
            app.WS.Value = 31;
            app.EdgetaperCheckBox = uicheckbox(app.MCISMPixelReassignmentPanel);
            app.EdgetaperCheckBox.Text = 'EdgeTaper';
            app.Edge = uieditfield(app.MCISMPixelReassignmentPanel, 'numeric');
            app.RunMSIMButton = uibutton(app.MCISMPixelReassignmentPanel, 'push');
            app.RunMSIMButton.ButtonPushedFcn = createCallbackFcn(app, @RunMSIMButtonPushed, true);
            app.RunMSIMButton.Text = 'Run';
            app.Label_13 = uilabel(app.MCISMPixelReassignmentPanel);
            app.Label_13.Text = 'Background: ';
            app.Bg = uieditfield(app.MCISMPixelReassignmentPanel, 'numeric');
            app.HardwareParametersPanel = uipanel(app.LeftPanel);
            app.HardwareParametersPanel.Title = 'Hardware Parameters';
            app.nmLabel = uilabel(app.HardwareParametersPanel);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.HardwareParametersPanel);
            app.nmLabel_2.Text = 'nm';
            app.Label_4 = uilabel(app.HardwareParametersPanel);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '$W_{Em}$:';
            app.EmW = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.EmW.Value = 685;
            app.NALabel = uilabel(app.HardwareParametersPanel);
            app.NALabel.Text = 'NA:';
            app.NumericalAperture = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.NumericalAperture.Value = 1.49;
            app.pixelsizeLabel = uilabel(app.HardwareParametersPanel);
            app.pixelsizeLabel.Text = 'pixel:';
            app.PixelSize = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.PixelSize.Value = 65;
            app.nmLabel_3 = uilabel(app.HardwareParametersPanel);
            app.nmLabel_3.Text = 'nm';
            app.Label_10 = uilabel(app.HardwareParametersPanel);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = '$N_{scan}$:';
            app.step = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.step.Value = 49;
            app.Label_12 = uilabel(app.HardwareParametersPanel);
            app.Label_12.Text = 'upfactor:';
            app.Upfactor = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.Upfactor.Value = 1;
            app.Label_3 = uilabel(app.HardwareParametersPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '$W_{Ex}$:';
            app.ExW = uieditfield(app.HardwareParametersPanel, 'numeric');
            app.ExW.Value = 647;
            app.LockinDeDc = uibutton(app.LeftPanel, 'push');
            app.LockinDeDc.ButtonPushedFcn = createCallbackFcn(app, @LockinDeDcButtonPushed, true);
            app.LockinDeDc.Text = 'OLID';
            app.PreprocessLabel = uilabel(app.LeftPanel);
            app.PreprocessLabel.Text = 'Preprocess:';
            app.ReadFile = uibutton(app.LeftPanel, 'push');
            app.ReadFile.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFile.Text = 'READ';
            app.ViewFile = uibutton(app.LeftPanel, 'push');
            app.ViewFile.ButtonPushedFcn = createCallbackFcn(app, @ViewFileButtonPushed, true);
            app.ViewFile.Text = 'view';
            app.Filepath = uieditfield(app.LeftPanel, 'text');
            app.Filepath.Value = 'C:\Users\1\Desktop\Example\Single_Slice';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label_11 = uilabel(app.RightPanel);
            app.Label_11.Text = 'Status Bar: Processing Progress';
            app.StatusBarProcessingProgressTextArea = uitextarea(app.RightPanel);
            app.StatusBarProcessingProgressTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Raw = uitab(app.TabGroup);
            app.Raw.Title = 'DISPLAY';
            app.RawImage = uiaxes(app.Raw);
            title(app.RawImage, 'Raw Image Viewer')
            app.RawImage.XLimitMethod = 'tight';
            app.RawImage.YLimitMethod = 'tight';
            app.RawImage.ZLimitMethod = 'tight';
            app.RawImage.XTick = [];
            app.RawImage.YTick = [];
            app.BatchProcessingTab = uitab(app.TabGroup);
            app.BatchProcessingTab.Title = 'Batch Processing';
            app.RunBatchButton = uibutton(app.BatchProcessingTab, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Text = 'Run';
            app.PipelineRefertotheparametersontheleftPanel = uipanel(app.BatchProcessingTab);
            app.PipelineRefertotheparametersontheleftPanel.Title = 'Pipeline: Refer to the parameters on the left';
            app.FISTABCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.FISTABCheckBox.Text = 'FISTA';
            app.jRLBCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.jRLBCheckBox.Text = 'jRL';
            app.PRBCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.PRBCheckBox.Text = 'PR';
            app.PinholeBCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.PinholeBCheckBox.Text = 'Illumination Pattern';
            app.LockinDeDCBCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.LockinDeDCBCheckBox.Text = 'Lockin DeDC';
            app.RawdataBCheckBox = uicheckbox(app.PipelineRefertotheparametersontheleftPanel);
            app.RawdataBCheckBox.Text = 'Data Arrangement';
            app.FileSetupPanel = uipanel(app.BatchProcessingTab);
            app.FileSetupPanel.Title = 'File Setup';
            app.TCheckBox = uicheckbox(app.FileSetupPanel);
            app.TCheckBox.Text = 'T';
            app.ZCheckBox = uicheckbox(app.FileSetupPanel);
            app.ZCheckBox.Text = 'Z';
            app.ZCheckBox.Value = true;
            app.Wavelength = uieditfield(app.FileSetupPanel, 'text');
            app.Wavelength.Value = '647,685;561,585;405,425';
            app.Background = uieditfield(app.FileSetupPanel, 'text');
            app.Background.Value = '125;200;90';
            app.CCheckBox = uicheckbox(app.FileSetupPanel);
            app.CCheckBox.Text = 'C';
            app.ImageSequenceLabel = uilabel(app.FileSetupPanel);
            app.ImageSequenceLabel.Text = 'Image Sequence：';
            app.Label_23 = uilabel(app.FileSetupPanel);
            app.Label_23.Text = 'File SavePath:';
            app.TEnd = uieditfield(app.FileSetupPanel, 'text');
            app.TEnd.Value = '1';
            app.TStart = uieditfield(app.FileSetupPanel, 'text');
            app.TStart.Value = '1';
            app.Label_25 = uilabel(app.FileSetupPanel);
            app.Label_25.Text = '—';
            app.Label_24 = uilabel(app.FileSetupPanel);
            app.Label_24.Text = '—';
            app.isrename = uicheckbox(app.FileSetupPanel);
            app.isrename.Text = 'Rename';
            app.isWF = uicheckbox(app.FileSetupPanel);
            app.isWF.Text = 'WF';
            app.isWF.Value = true;
            app.SlowAxisDummy = uicheckbox(app.FileSetupPanel);
            app.SlowAxisDummy.Text = 'Extra SA';
            app.FastAixsDummy = uicheckbox(app.FileSetupPanel);
            app.FastAixsDummy.Text = 'Extra FA';
            app.ViewFileSaveBatch = uibutton(app.FileSetupPanel, 'push');
            app.ViewFileSaveBatch.ButtonPushedFcn = createCallbackFcn(app, @ViewFileSaveBatchButtonPushed, true);
            app.ViewFileSaveBatch.Text = 'view';
            app.FilepathSaveBatch = uieditfield(app.FileSetupPanel, 'text');
            app.FilepathSaveBatch.Value = 'C:\Users\1\Desktop\Example\Multiple_Slice\grouped_data';
            app.Label_15 = uilabel(app.FileSetupPanel);
            app.Label_15.Text = 'Original FilePath:';
            app.FilepathBatch = uieditfield(app.FileSetupPanel, 'text');
            app.FilepathBatch.Value = 'C:\Users\1\Desktop\Example\Multiple_Slice';
            app.ViewFileBatch = uibutton(app.FileSetupPanel, 'push');
            app.ViewFileBatch.ButtonPushedFcn = createCallbackFcn(app, @ViewFileBatchButtonPushed, true);
            app.ViewFileBatch.Text = 'view';
            app.Zend = uieditfield(app.FileSetupPanel, 'numeric');
            app.Zend.Value = 20;
            app.Zstart = uieditfield(app.FileSetupPanel, 'numeric');
            app.Zstart.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MCISMXi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end